classdef firstPrototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WeightkgEditField               matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel          matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        predictedHealthEditField        matlab.ui.control.EditField
        predictedHealthEditFieldLabel   matlab.ui.control.Label
        insomnia                        matlab.ui.control.DropDown
        HaveyouhadsleepingproblemsthelastweekDropDownLabel  matlab.ui.control.Label
        MentalhealthindexEditField      matlab.ui.control.NumericEditField
        MentalhealthindexEditFieldLabel  matlab.ui.control.Label
        friendsSupp                     matlab.ui.control.DropDown
        DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel  matlab.ui.control.Label
        smoke                           matlab.ui.control.DropDown
        DoyousmokecigarettesdailyDropDownLabel  matlab.ui.control.Label
        physact                         matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        hypertension                    matlab.ui.control.DropDown
        DoyouhavehypertensionLabel      matlab.ui.control.Label
        PredictmyhealthButton           matlab.ui.control.Button
        ExerciseFrq                     matlab.ui.control.DropDown
        HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel  matlab.ui.control.Label
        diabetes                        matlab.ui.control.DropDown
        DoyouhavediabetesDropDownLabel  matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        heartFailureOrStroke            matlab.ui.control.DropDown
        HaveyoueverhadastrokeorheartfailureDropDownLabel  matlab.ui.control.Label
        liveWithSpouse                  matlab.ui.control.DropDown
        DoyoulivewithaspousepartnerDropDownLabel  matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictmyhealthButton
        function PredictmyhealthButtonPushed(app, event)
            bobba.id = 0;
            bobba.srh = 2;
            bobba.mentIll = app.MentalhealthindexEditField.Value;
            bobba.age = app.AgeEditField.Value/10;
            
            bobba.old         = categorical(double(bobba.age>=65));
            
            bobba.sex            = categorical(double(app.SexDropDown.Value=="female"));
            bobba.smokeNow       = categorical(double(app.smoke.Value=="yes"));
            bobba.hypert         = categorical(double(app.hypertension.Value=="yes"));
            bobba.diabHba1c      = categorical(double(app.diabetes=="yes"));
            bobba.strokeOrHrtAtk = categorical(double(app.heartFailureOrStroke=="yes"));
            bobba.friendsSupp    = categorical(double(app.friendsSupp=="yes"));
            bobba.liveWspouse    = categorical(double(app.liveWithSpouse=="yes"));

            height = app.HeightmEditField.Value;
            weight = app.WeightkgEditField.Value;
            bobba.bmi = categorical(discretize(weight/height^2,[0,18.5,25,30,inf]));
            
            PAfrq = char(app.ExerciseFrq.Value);
            if PAfrq(1)=="1" || PAfrq(1)=="2"
                bobba.PAfrq = categorical(1);
            elseif PAfrq(1)=="3" || PAfrq(1)=="4"
                bobba.PAfrq = categorical(2);
            else
                bobba.PAfrq = categorical(3);
            end
            bobba.PAfrq3 = categorical(double(bobba.PAfrq=="3"));
            
            PA = char(app.physact.Value);
            bobba.physact = PA(1);
            bobba.physact34 = categorical(double(PA(1)>=3));
            
            insomn = char(app.insomnia.Value);
            bobba.insomnia = categorical(string(insomn(1)));
            bobba.insomnia4 = categorical(double(bobba.insomnia=="4"));
            
            bobba = struct2table(bobba);
            
            % define which variables to present:
            % decisVars = {'PAfrq','physact34','bmi','glucoseHemog','mentHealthInd','insomnia'};
            decisVars = {'PAfrq','physact34','bmi','diabHba1c','mentIll','insomnia','smokeNow'};
            combos = {{'PAfrq'   ,'physact34'} ,[3,1];...
                      {'physact34', 'smokeNow','insomnia'} ,[1,0,1]};
            
            load('C:\Users\perwa\OneDrive - UiT Office 365\Health companion app\scripts\saved files\dataSummary.mat','S');
            load('C:\Users\perwa\OneDrive - UiT Office 365\Health companion app\scripts\saved files\models\modelJune16.mat','lme');
            app.predictedHealthEditField.Value = string(lme.predict(bobba));
            
            if isnan(lme.predict(bobba))
                fieldNames = bobba.Properties.VariableNames;
                for i=1:numel(fieldNames)
                    if ismissing(string(bobba{1,i}))
                        app.predictedHealthEditField.Value = sprintf('%s: missing',fieldNames{i});
                        break
                    else
                        app.predictedHealthEditField.Value = sprintf('%s: %s',fieldNames{i},string(bobba{1,i}));
                    end
                    pause(1)
                end
            end
            
            close all
            figure
            userBasedModelPresentation(bobba,S,lme,decisVars,combos)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoyoulivewithaspousepartnerDropDownLabel = uilabel(app.UIFigure);
            app.DoyoulivewithaspousepartnerDropDownLabel.Text = 'Do you live with a spouse/partner?';
            app.liveWithSpouse = uidropdown(app.UIFigure);
            app.liveWithSpouse.Items = {'no', 'yes'};
            app.liveWithSpouse.Value = 'yes';
            app.HaveyoueverhadastrokeorheartfailureDropDownLabel = uilabel(app.UIFigure);
            app.HaveyoueverhadastrokeorheartfailureDropDownLabel.Text = 'Have you ever had a stroke or heart failure?';
            app.heartFailureOrStroke = uidropdown(app.UIFigure);
            app.heartFailureOrStroke.Items = {'no', 'yes'};
            app.heartFailureOrStroke.Value = 'no';
            app.SexDropDownLabel = uilabel(app.UIFigure);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.UIFigure);
            app.SexDropDown.Items = {'female', 'male'};
            app.SexDropDown.Value = 'female';
            app.DoyouhavediabetesDropDownLabel = uilabel(app.UIFigure);
            app.DoyouhavediabetesDropDownLabel.Text = 'Do you have diabetes?';
            app.diabetes = uidropdown(app.UIFigure);
            app.diabetes.Items = {'no', 'yes'};
            app.diabetes.Value = 'no';
            app.HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel = uilabel(app.UIFigure);
            app.HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel.Text = {'How often do you exercise (i.e.walking, '; 'swiming, skiing or training/sports?'};
            app.ExerciseFrq = uidropdown(app.UIFigure);
            app.ExerciseFrq.Items = {'1. never', '2. Less than once per week', '3. Once a week', '4. 2-3 times a week', '5. Approximately every day'};
            app.ExerciseFrq.Value = '1. never';
            app.PredictmyhealthButton = uibutton(app.UIFigure, 'push');
            app.PredictmyhealthButton.ButtonPushedFcn = createCallbackFcn(app, @PredictmyhealthButtonPushed, true);
            app.PredictmyhealthButton.Text = 'Predict my health';
            app.DoyouhavehypertensionLabel = uilabel(app.UIFigure);
            app.DoyouhavehypertensionLabel.Text = 'Do you have hypertension?';
            app.hypertension = uidropdown(app.UIFigure);
            app.hypertension.Items = {'no', 'yes'};
            app.hypertension.Value = 'no';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = {'Have you engaged in intense (sports/sweating/out of breath) physical exercise on average atleast 4 hours per week'; 'over the last 12 months? '};
            app.physact = uidropdown(app.UIFigure);
            app.physact.Items = {'no', 'yes'};
            app.physact.Value = 'no';
            app.DoyousmokecigarettesdailyDropDownLabel = uilabel(app.UIFigure);
            app.DoyousmokecigarettesdailyDropDownLabel.Text = 'Do you smoke cigarettes daily?';
            app.smoke = uidropdown(app.UIFigure);
            app.smoke.Items = {'no', 'yes'};
            app.smoke.Value = 'no';
            app.DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel = uilabel(app.UIFigure);
            app.DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel.Text = 'Do you have enough friends who can give you help and support when you need it?';
            app.friendsSupp = uidropdown(app.UIFigure);
            app.friendsSupp.Items = {'yes', 'no'};
            app.friendsSupp.Value = 'yes';
            app.MentalhealthindexEditFieldLabel = uilabel(app.UIFigure);
            app.MentalhealthindexEditFieldLabel.Text = 'Mental health index';
            app.MentalhealthindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.HaveyouhadsleepingproblemsthelastweekDropDownLabel = uilabel(app.UIFigure);
            app.HaveyouhadsleepingproblemsthelastweekDropDownLabel.Text = 'Have you had sleeping problems the last week?';
            app.insomnia = uidropdown(app.UIFigure);
            app.insomnia.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.insomnia.Value = '1. No complaint';
            app.predictedHealthEditFieldLabel = uilabel(app.UIFigure);
            app.predictedHealthEditFieldLabel.Text = 'predicted Health';
            app.predictedHealthEditField = uieditfield(app.UIFigure, 'text');
            app.HeightmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmEditField.Value = 1.8;
            app.WeightkgEditFieldLabel = uilabel(app.UIFigure);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = firstPrototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end