classdef secondPrototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        liveWithSpouse                  matlab.ui.control.DropDown
        DoyoulivewithaspousepartnerLabel  matlab.ui.control.Label
        EducationDropDown               matlab.ui.control.DropDown
        EducationDropDownLabel          matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexDropDownLabel                matlab.ui.control.Label
        WeightkgEditField               matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel          matlab.ui.control.Label
        HeightmEditField                matlab.ui.control.NumericEditField
        HeightmEditFieldLabel           matlab.ui.control.Label
        AgeEditField                    matlab.ui.control.NumericEditField
        AgeEditFieldLabel               matlab.ui.control.Label
        HealthstatusTab                 matlab.ui.container.Tab
        hii                             matlab.ui.control.NumericEditField
        WhatisyourHealthimparcindexEditFieldLabel  matlab.ui.control.Label
        highBP                          matlab.ui.control.DropDown
        DoyouhavehypertensionLabel      matlab.ui.control.Label
        diabetes                        matlab.ui.control.DropDown
        DoyouhavediabetesDropDownLabel  matlab.ui.control.Label
        heartFailureOrStroke            matlab.ui.control.DropDown
        HaveyoueverhadastrokeorheartfailureDropDownLabel  matlab.ui.control.Label
        HabitsTab                       matlab.ui.container.Tab
        PAint                           matlab.ui.control.DropDown
        IfyouexercisehowharddoyouexerciseLabel  matlab.ui.control.Label
        smoke                           matlab.ui.control.DropDown
        DoyousmokecigarettesdailyDropDownLabel  matlab.ui.control.Label
        PAfrq                           matlab.ui.control.DropDown
        HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel  matlab.ui.control.Label
        SocialTab                       matlab.ui.container.Tab
        friendsSupp                     matlab.ui.control.DropDown
        DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel  matlab.ui.control.Label
        MentalhealthTab                 matlab.ui.container.Tab
        future                          matlab.ui.control.DropDown
        HaveyoufeltanxiousorworriedthelastweekDropDown_10Label  matlab.ui.control.Label
        struggle                        matlab.ui.control.DropDown
        HaveyoufeltthateverythingisastruggleduringthelastweekLabel  matlab.ui.control.Label
        useless                         matlab.ui.control.DropDown
        HaveyoufeltuselessworthlessduringthelastweekLabel  matlab.ui.control.Label
        depr                            matlab.ui.control.DropDown
        HaveyoufeltdepressedorsadduringthelastweekLabel  matlab.ui.control.Label
        insomnia                        matlab.ui.control.DropDown
        HaveyouhadsleepingproblemsduringthelastweekLabel  matlab.ui.control.Label
        blameSelf                       matlab.ui.control.DropDown
        HaveyoueasilyblamedyourselfduringthelastweekLabel  matlab.ui.control.Label
        tense                           matlab.ui.control.DropDown
        HaveyoufelttenseorupsetduringthelastweekLabel  matlab.ui.control.Label
        dizzy                           matlab.ui.control.DropDown
        HaveyouexperiencedfaintnessordizzinessduringthelastweekLabel  matlab.ui.control.Label
        worried                         matlab.ui.control.DropDown
        HaveyoufeltafraidoranxiousduringthelastweekLabel  matlab.ui.control.Label
        fear                            matlab.ui.control.DropDown
        HaveyoufeltanxiousorworriedthelastweekLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        WhatshouldIprioritizeButton     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: WhatshouldIprioritizeButton
        function WhatshouldIprioritizeButtonPushed(app, event)
            % These variables are arbitrary, and do not impact output
            id  = 0;
            srh = 2;
            t = 7;

            % ¤¤¤ General Info ¤¤¤
            age = app.AgeEditField.Value/10;
            sex = categorical(double(app.SexDropDown.Value=="male"));
            weight = app.WeightkgEditField.Value;
            height = app.HeightmEditField.Value;
            education = categorical(str2double( ...
                app.EducationDropDown.Value(1)));
            
            % ¤¤¤ Diseases, habits and social ¤¤¤
            smokeNow       = categorical(double(app.smoke.Value=="yes"));
            highBP         = categorical(double(app.highBP.Value=="yes"));
            diabHba1c      = categorical(double(app.diabetes.Value=="yes"));
            strokeOrHrtAtk = categorical(double(app.heartFailureOrStroke.Value=="yes"));
            hii = app.hii.Value;
            friendsSupp    = categorical(double(app.friendsSupp.Value=="yes"));
            liveWspouse    = categorical(double(app.liveWithSpouse.Value=="yes"));
            
            % ¤¤¤ Exercise ¤¤¤
            PAfrq = categorical(str2double(app.PAfrq.Value(1)));
%             if PAfrq(1)=="1" || PAfrq(1)=="2"
%                 PAfrq = categorical(1);
%             elseif PAfrq(1)=="3" || PAfrq(1)=="4"
%                 PAfrq = categorical(2);
%             else
%                 PAfrq = categorical(3);
%             end
            
            PAint = char(app.PAint.Value);
            if PAint(1)=="0" || PAint(1)=="1"
                PAint = categorical(1);
            elseif PAint(1)=="2"
                PAint = categorical(2);
            else
                PAint = categorical(3);
            end
            
            % ¤¤¤ HSCL ¤¤¤
            futureView = categorical(string(app.future.Value(1)));
            struggle   = categorical(string(app.struggle.Value(1)));
            useless    = categorical(string(app.useless.Value(1)));
            depr       = categorical(string(app.depr.Value(1)));
            blameSelf  = categorical(string(app.blameSelf.Value(1)));
            tense      = categorical(string(app.tense.Value(1)));
            dizzy      = categorical(string(app.dizzy.Value(1)));
            worried    = categorical(string(app.worried.Value(1)));
            fear       = categorical(string(app.fear.Value(1)));
            insomnia   = categorical(string(app.insomnia.Value(1))); %#ok<*ADPROPLC> 
            
            T = table(t,id,srh,age,sex,weight,height,education, ...
                      smokeNow,highBP, ...
                      diabHba1c,strokeOrHrtAtk,hii, ...
                      friendsSupp,liveWspouse, ...
                      PAfrq,PAint, ...
                      futureView,struggle,useless,depr,blameSelf, ...
                      tense,dizzy,worried,fear,insomnia);

            % Add paths 
            addPaths
            % Save user to file
            user_tables_dir = fullfile(navi.paths.matlabFiles,"user-tables");
            user_file = fullfile(user_tables_dir, "T_user.mat");
            save(user_file, 'T')

            % load model:
            model_file = fullfile(navi.paths.models, "lme.mat");
            load(model_file,'lme');

            close all
            main_plotSuggestedChanges(T,lme)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {433, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.AgeEditFieldLabel = uilabel(app.GeneralTab);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.GeneralTab, 'numeric');
            app.AgeEditField.Value = 40;
            app.HeightmEditFieldLabel = uilabel(app.GeneralTab);
            app.HeightmEditFieldLabel.Text = 'Height (m)';
            app.HeightmEditField = uieditfield(app.GeneralTab, 'numeric');
            app.HeightmEditField.Value = 1.75;
            app.WeightkgEditFieldLabel = uilabel(app.GeneralTab);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.GeneralTab, 'numeric');
            app.WeightkgEditField.Value = 90;
            app.SexDropDownLabel = uilabel(app.GeneralTab);
            app.SexDropDownLabel.Text = 'Sex';
            app.SexDropDown = uidropdown(app.GeneralTab);
            app.SexDropDown.Items = {'female', 'male'};
            app.SexDropDown.Value = 'female';
            app.EducationDropDownLabel = uilabel(app.GeneralTab);
            app.EducationDropDownLabel.Text = 'Education';
            app.EducationDropDown = uidropdown(app.GeneralTab);
            app.EducationDropDown.Items = {'1. upper secondary education', '2. high school diploma', '3. college/university'};
            app.EducationDropDown.Value = '1. upper secondary education';
            app.DoyoulivewithaspousepartnerLabel = uilabel(app.GeneralTab);
            app.DoyoulivewithaspousepartnerLabel.Text = {'Do you live with a'; ' spouse/partner?'};
            app.liveWithSpouse = uidropdown(app.GeneralTab);
            app.liveWithSpouse.Items = {'no', 'yes'};
            app.liveWithSpouse.Value = 'yes';
            app.HealthstatusTab = uitab(app.TabGroup);
            app.HealthstatusTab.Title = 'Health status';
            app.HaveyoueverhadastrokeorheartfailureDropDownLabel = uilabel(app.HealthstatusTab);
            app.HaveyoueverhadastrokeorheartfailureDropDownLabel.Text = 'Have you ever had a stroke or heart failure?';
            app.heartFailureOrStroke = uidropdown(app.HealthstatusTab);
            app.heartFailureOrStroke.Items = {'no', 'yes'};
            app.heartFailureOrStroke.Value = 'no';
            app.DoyouhavediabetesDropDownLabel = uilabel(app.HealthstatusTab);
            app.DoyouhavediabetesDropDownLabel.Text = 'Do you have diabetes?';
            app.diabetes = uidropdown(app.HealthstatusTab);
            app.diabetes.Items = {'no', 'yes'};
            app.diabetes.Value = 'yes';
            app.DoyouhavehypertensionLabel = uilabel(app.HealthstatusTab);
            app.DoyouhavehypertensionLabel.Text = 'Do you have high blood pressure?';
            app.highBP = uidropdown(app.HealthstatusTab);
            app.highBP.Items = {'no', 'yes'};
            app.highBP.Value = 'yes';
            app.WhatisyourHealthimparcindexEditFieldLabel = uilabel(app.HealthstatusTab);
            app.WhatisyourHealthimparcindexEditFieldLabel.Text = 'What is your  Health imparc index?';
            app.hii = uieditfield(app.HealthstatusTab, 'numeric');
            app.hii.Value = 1;
            app.HabitsTab = uitab(app.TabGroup);
            app.HabitsTab.Title = 'Habits';
            app.HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel = uilabel(app.HabitsTab);
            app.HowoftendoyouexerciseiewalkingswimingskiingDropDownLabel.Text = {'How often do you exercise (i.e.walking, '; 'swiming, skiing or training/sports)?'};
            app.PAfrq = uidropdown(app.HabitsTab);
            app.PAfrq.Items = {'1. Less than once per week', '2. Once a week', '3. 2-3 times a week', '4. 4 or more times per week'};
            app.PAfrq.Value = '1. Less than once per week';
            app.DoyousmokecigarettesdailyDropDownLabel = uilabel(app.HabitsTab);
            app.DoyousmokecigarettesdailyDropDownLabel.Text = 'Do you smoke cigarettes daily?';
            app.smoke = uidropdown(app.HabitsTab);
            app.smoke.Items = {'no', 'yes'};
            app.smoke.Value = 'no';
            app.IfyouexercisehowharddoyouexerciseLabel = uilabel(app.HabitsTab);
            app.IfyouexercisehowharddoyouexerciseLabel.Text = 'If you exercise, how hard do you exercise?';
            app.PAint = uidropdown(app.HabitsTab);
            app.PAint.Items = {'1. I do not exercise', '2. Mild - you do not become shortwinded or sweaty ', '3. Medium - you do become shortwinded or sweaty ', '4. Hard - you become exhausted'};
            app.PAint.Value = '1. I do not exercise';
            app.SocialTab = uitab(app.TabGroup);
            app.SocialTab.Title = 'Social';
            app.DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel = uilabel(app.SocialTab);
            app.DoyouhaveenoughfriendsyoucantalkconfidentiallywithDropDownLabel.Text = 'Do you have enough friends who can give you help and support when you need it?';
            app.friendsSupp = uidropdown(app.SocialTab);
            app.friendsSupp.Items = {'yes', 'no'};
            app.friendsSupp.Value = 'yes';
            app.MentalhealthTab = uitab(app.TabGroup);
            app.MentalhealthTab.Title = 'Mental health';
            app.HaveyoufeltanxiousorworriedthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufeltanxiousorworriedthelastweekLabel.Text = {'Have you experienced sudden fear without '; 'apparent reason during the last week?'; ''};
            app.fear = uidropdown(app.MentalhealthTab);
            app.fear.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.fear.Value = '3. Pretty much';
            app.HaveyoufeltafraidoranxiousduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufeltafraidoranxiousduringthelastweekLabel.Text = 'Have you felt afraid or anxious during the last week?';
            app.worried = uidropdown(app.MentalhealthTab);
            app.worried.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.worried.Value = '2. Little complaint';
            app.HaveyouexperiencedfaintnessordizzinessduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyouexperiencedfaintnessordizzinessduringthelastweekLabel.Text = {'Have you experienced faintness or '; 'dizziness during the last week?'};
            app.dizzy = uidropdown(app.MentalhealthTab);
            app.dizzy.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.dizzy.Value = '1. No complaint';
            app.HaveyoufelttenseorupsetduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufelttenseorupsetduringthelastweekLabel.Text = {'Have you felt tense or upset during the last '; 'week?'};
            app.tense = uidropdown(app.MentalhealthTab);
            app.tense.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.tense.Value = '1. No complaint';
            app.HaveyoueasilyblamedyourselfduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoueasilyblamedyourselfduringthelastweekLabel.Text = {'Have you easily blamed yourself'; 'during the last week?'};
            app.blameSelf = uidropdown(app.MentalhealthTab);
            app.blameSelf.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.blameSelf.Value = '3. Pretty much';
            app.HaveyouhadsleepingproblemsduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyouhadsleepingproblemsduringthelastweekLabel.Text = {'Have you had sleeping problems '; 'during the last week?'};
            app.insomnia = uidropdown(app.MentalhealthTab);
            app.insomnia.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.insomnia.Value = '1. No complaint';
            app.HaveyoufeltdepressedorsadduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufeltdepressedorsadduringthelastweekLabel.Text = 'Have you felt depressed or sad during the last week?';
            app.depr = uidropdown(app.MentalhealthTab);
            app.depr.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.depr.Value = '3. Pretty much';
            app.HaveyoufeltuselessworthlessduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufeltuselessworthlessduringthelastweekLabel.Text = {'Have you felt useless, worthless during'; 'the last week?'};
            app.useless = uidropdown(app.MentalhealthTab);
            app.useless.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.useless.Value = '3. Pretty much';
            app.HaveyoufeltthateverythingisastruggleduringthelastweekLabel = uilabel(app.MentalhealthTab);
            app.HaveyoufeltthateverythingisastruggleduringthelastweekLabel.Text = {'Have you felt that everything is a struggle '; 'during the last week?'};
            app.struggle = uidropdown(app.MentalhealthTab);
            app.struggle.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.struggle.Value = '3. Pretty much';
            app.HaveyoufeltanxiousorworriedthelastweekDropDown_10Label = uilabel(app.MentalhealthTab);
            app.HaveyoufeltanxiousorworriedthelastweekDropDown_10Label.Text = 'Have you felt hopelessness with regard to the future during the last week?';
            app.future = uidropdown(app.MentalhealthTab);
            app.future.Items = {'1. No complaint', '2. Little complaint', '3. Pretty much', '4. Very much'};
            app.future.Value = '3. Pretty much';
            app.RightPanel = uipanel(app.GridLayout);
            app.WhatshouldIprioritizeButton = uibutton(app.RightPanel, 'push');
            app.WhatshouldIprioritizeButton.ButtonPushedFcn = createCallbackFcn(app, @WhatshouldIprioritizeButtonPushed, true);
            app.WhatshouldIprioritizeButton.Text = 'What should I prioritize?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = secondPrototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end